classdef climatewedgesV004 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadFileMenu               matlab.ui.container.Menu
        LoadWedgesFileMenu         matlab.ui.container.Menu
        LoadConstraintsFileMenu    matlab.ui.container.Menu
        LoadStrategyFileMenu       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
        StartYearTextAreaLabel     matlab.ui.control.Label
        StartYearTextArea          matlab.ui.control.TextArea
        EndYearTextAreaLabel       matlab.ui.control.Label
        EndYearTextArea            matlab.ui.control.TextArea
        EnterastartandendLabel     matlab.ui.control.Label
        yeartoeditthegraphsLabel   matlab.ui.control.Label
        domainLabel                matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        RemoveButton               matlab.ui.control.StateButton
        AddButton                  matlab.ui.control.StateButton
        SelectionOptions           matlab.ui.control.ListBox
        SelectionOptionsEndYear    matlab.ui.control.Spinner
        SelectionOptionsStartYear  matlab.ui.control.Spinner
        SelectedOptions            matlab.ui.control.ListBox
    end


    properties (Access = private)
        WedgesData      % This property will hold processed data.
        ConstraintsData % This property will hold processed data.
        StrategyData    % This property will hold processed data.
        XDataHist  %Stores historical XData
        YDataHist  %Stores historical YData
        ChosenWedges % Stores the wedges chosen by the user.
    end

    methods (Access = private)
    
    function Data = readFile(app, fileName)
        % This fucntion's purpose is to read in and process
        % the any of the input files and outputs the data as a struct.
        % You must access data in the file using parenthesis at the begining of
        % the call. i.e. Data(n).field the nth element in that field.
        try
            Data = readtable(fileName);
            Data = table2struct(Data,'ToScalar',true);
            
        % Data Checking
        % Data Checking is done by using the field names.
        % Assuming the header is provided the same in every file, 
        % the field names will be consistent.
    
            identifier = fieldnames(Data);
            identifier = identifier{2};
        
        % Check for the wedges file
            if strcmp(identifier, 'Category')
                Data.Flag = 'wedges';
        
        % Check for the constraints file
            elseif strcmp(identifier, 'YearStart')
                Data.Flag = 'strategy';
                
        % Check for the strategy File
            elseif strcmp(identifier, 'Constraints')
                Data.Flag = 'constraints';
        
        % If it doesn't fit any of the above formats, it is not a useable file.
            else
                error
            end
        catch
            Data = -1;
        end
    end
end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            info = tdfread('historicaldata.txt');
            app.XDataHist = info.Year;
            app.YDataHist = info.fossil_fuel_and_industry_GtC0x2Fyr;
            plot(app.UIAxes, app.XDataHist, app.YDataHist);
            app.UIAxes.XLim = [app.XDataHist(1), app.XDataHist(end)];
            app.UIAxes.YLim = [app.YDataHist(1), app.YDataHist(end)];
        end

        % Menu selected function: LoadWedgesFileMenu
        function LoadWedgesFileMenuSelected(app, event)
            Data = readFile(app, uigetfile('.txt'));
            
            if ~isequal(Data, -1) && strcmp(Data.Flag, 'wedges')
                
                app.SelectionOptions.Items = Data.StrategyName;
                climatewedge.WedgesData = Data;
                
            else
                disp('Please select a valid file...');
            end
        end

        % Callback function
        function ElectricityStrategiesListBoxValueChanged(app, event)
            
            value = app.ElectricityStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.ElectricityStrategiesListBox.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Callback function
        function TransportationStrategiesListBoxValueChanged(app, event)
            
            value = app.TransportationStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.TransportationStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Callback function
        function FuelStrategiesListBoxValueChanged(app, event)
            
            value = app.FuelStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.FuelStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Callback function
        function BiostorageStrategiesListBoxValueChanged(app, event)
           
            value = app.BiostorageStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.BiostorageStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: StartYearTextArea
        function StartYearTextAreaValueChanged(app, event)
             %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
            
            value = app.StartYearTextArea.Value;
            
            if value > 99
                app.UIAxes.XLim = [app.XData(1), app.XData(end)];
            elseif value < 2
                app.UIAxes.XLim = [app.XData(1), app.XData(2)];
            else
                app.UIAxes.XLim = [app.XData(1), app.XData(floor(end*(value/100)))];
            end
            
        end

        % Value changed function: EndYearTextArea
        function EndYearTextAreaValueChanged(app, event)
              %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
            
            value = app.EndYearTextArea.Value;
            if value > 99
                app.UIAxes.XLim = [app.XData(1), app.XData(end)];
            elseif value < 2
                app.UIAxes.XLim = [app.XData(1), app.XData(2)];
            else
                app.UIAxes.XLim = [app.XData(1), app.XData(floor(end*(value/100)))];
            end
            
        end

        % Menu selected function: LoadConstraintsFileMenu
        function LoadConstraintsFileMenuSelected(app, event)
            
            code = readFile(app, uigetfile('.txt'));
            
            if ~isequal(code, -1)
                climatewedge.ConstraintsData = code;
            else
                disp('Please select a valid file...');
            end
            
        end

        % Menu selected function: LoadStrategyFileMenu
        function LoadStrategyFileMenuSelected(app, event)
            
            code = readFile(app, uigetfile('.txt'));
            
            if ~isequal(code, -1)
                climatewedge.StrategyData = code;
            else
                disp('Please select a valid file...');
            end
            
        end

        % Value changed function: AddButton
        function AddButtonValueChanged(app, event)
            value = app.AddButton.Value;
            selection = app.SelectionOptions.Value;
            app.SelectedOptions.Items = [app.SelectedOptions.Items selection]; 
        end

        % Value changed function: RemoveButton
        function RemoveButtonValueChanged(app, event)
            value = app.RemoveButton.Value;
            selection = app.SelectedOptions.Value;
            
            app.SelectedOptions.Value = ;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadFileMenu = uimenu(app.UIFigure);
            app.LoadFileMenu.Text = 'Load File';
            app.LoadWedgesFileMenu = uimenu(app.LoadFileMenu);
            app.LoadWedgesFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadWedgesFileMenuSelected, true);
            app.LoadWedgesFileMenu.Text = 'Load Wedges File';
            app.LoadConstraintsFileMenu = uimenu(app.LoadFileMenu);
            app.LoadConstraintsFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConstraintsFileMenuSelected, true);
            app.LoadConstraintsFileMenu.Text = 'Load Constraints File';
            app.LoadStrategyFileMenu = uimenu(app.LoadFileMenu);
            app.LoadStrategyFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadStrategyFileMenuSelected, true);
            app.LoadStrategyFileMenu.Text = 'Load Strategy File';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.StartYearTextAreaLabel = uilabel(app.UIFigure);
            app.StartYearTextAreaLabel.Text = 'Start Year';
            app.StartYearTextArea = uitextarea(app.UIFigure);
            app.StartYearTextArea.ValueChangedFcn = createCallbackFcn(app, @StartYearTextAreaValueChanged, true);
            app.EndYearTextAreaLabel = uilabel(app.UIFigure);
            app.EndYearTextAreaLabel.Text = 'End Year';
            app.EndYearTextArea = uitextarea(app.UIFigure);
            app.EndYearTextArea.ValueChangedFcn = createCallbackFcn(app, @EndYearTextAreaValueChanged, true);
            app.EnterastartandendLabel = uilabel(app.UIFigure);
            app.EnterastartandendLabel.Text = 'Enter a start and end';
            app.yeartoeditthegraphsLabel = uilabel(app.UIFigure);
            app.yeartoeditthegraphsLabel.Text = 'year to edit the graph''s';
            app.domainLabel = uilabel(app.UIFigure);
            app.domainLabel.Text = 'domain.';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RemoveButton = uibutton(app.UIFigure, 'state');
            app.RemoveButton.ValueChangedFcn = createCallbackFcn(app, @RemoveButtonValueChanged, true);
            app.RemoveButton.Text = 'Remove';
            app.AddButton = uibutton(app.UIFigure, 'state');
            app.AddButton.ValueChangedFcn = createCallbackFcn(app, @AddButtonValueChanged, true);
            app.AddButton.Text = 'Add';
            app.SelectionOptions = uilistbox(app.UIFigure);
            app.SelectionOptions.Items = {};
            app.SelectionOptions.Multiselect = 'on';
            app.SelectionOptions.Value = {};
            app.SelectionOptionsEndYear = uispinner(app.UIFigure);
            app.SelectionOptionsStartYear = uispinner(app.UIFigure);
            app.SelectedOptions = uilistbox(app.UIFigure);
            app.SelectedOptions.Items = {};
            app.SelectedOptions.Multiselect = 'on';
            app.SelectedOptions.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedgesV004

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end